classdef addRoi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddcellmarksUIFigure  matlab.ui.Figure
        Toolbar               matlab.ui.container.Toolbar
        PushTool              matlab.ui.container.toolbar.PushTool
        GridLayout            matlab.ui.container.GridLayout
        ExistingROIsLabel     matlab.ui.control.Label
        GridLayout2           matlab.ui.container.GridLayout
        UITable               matlab.ui.control.Table
        xpshiftSwitch         matlab.ui.control.Switch
        xpshiftSwitchLabel    matlab.ui.control.Label
        HeightSpinner         matlab.ui.control.Spinner
        HeightSpinnerLabel    matlab.ui.control.Label
        WidthSpinner          matlab.ui.control.Spinner
        WidthSpinnerLabel     matlab.ui.control.Label
        MarksizeLabel         matlab.ui.control.Label
        CelltypeButtonGroup   matlab.ui.container.ButtonGroup
        Category8Button       matlab.ui.control.ToggleButton
        Category7Button       matlab.ui.control.ToggleButton
        Category6Button       matlab.ui.control.ToggleButton
        Category5Button       matlab.ui.control.ToggleButton
        Category4Button       matlab.ui.control.ToggleButton
        Category3Button       matlab.ui.control.ToggleButton
        Category2Button       matlab.ui.control.ToggleButton
        Category1Button       matlab.ui.control.ToggleButton
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        id = 1;
        rois = dictionary;% Description
        categories_names = ["Category 1", "Category 2","Category 3","Category 4","Category 5","Category 6","Category 7","Category 8"];
        categories_colors = ["r", "g", "b", "c", "m", "y", "k", "w"];
        categories = dictionary;
    end
    
    methods (Access = private)
        
        function addNewRoi(app, x, y, w, h)
            app.rois(app.id) = roi(app.id, "", app.CelltypeButtonGroup.SelectedObject.Text, x, y, w, h);
            app.UITable.Data = [app.UITable.Data; {'', app.CelltypeButtonGroup.SelectedObject.Text}];
            app.id = app.id+1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, img, autoDetect)
            image(app.UIAxes, img, 'HitTest', 'off');
            app.categories = dictionary(app.categories_names, app.categories_colors);
            app.rois = dictionary;
            app.id = 1;
        end

        % Callback function: PushTool
        function PushToolClicked(app, event)
            %save and go to basic view
%             errordlg('kliknalem!');
        end

        % Button down function: UIAxes
        function ImageClicked2(app, event)
            w = double(app.WidthSpinner.Value);
            h = double(app.HeightSpinner.Value);
            x = event.IntersectionPoint(1)-w/2;
            y = event.IntersectionPoint(2)-h/2;
            drawrectangle(app.UIAxes, 'Position', [x,y,w,h], 'Color', app.categories(app.CelltypeButtonGroup.SelectedObject.Text));
            app.addNewRoi(x, y, w, h);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AddcellmarksUIFigure = uifigure('Visible', 'off');
            app.AddcellmarksUIFigure.Name = 'Add cell marks';
            app.Toolbar = uitoolbar(app.AddcellmarksUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'save.png');
            app.GridLayout = uigridlayout(app.AddcellmarksUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @ImageClicked2, true);
            app.CelltypeButtonGroup = uibuttongroup(app.GridLayout);
            app.CelltypeButtonGroup.Title = 'Cell type';
            app.Category1Button = uitogglebutton(app.CelltypeButtonGroup);
            app.Category1Button.Text = 'Category 1';
            app.Category1Button.Value = true;
            app.Category2Button = uitogglebutton(app.CelltypeButtonGroup);
            app.Category2Button.Text = 'Category 2';
            app.Category3Button = uitogglebutton(app.CelltypeButtonGroup);
            app.Category3Button.Text = 'Category 3';
            app.Category4Button = uitogglebutton(app.CelltypeButtonGroup);
            app.Category4Button.Text = 'Category 4';
            app.Category5Button = uitogglebutton(app.CelltypeButtonGroup);
            app.Category5Button.Text = 'Category 5';
            app.Category6Button = uitogglebutton(app.CelltypeButtonGroup);
            app.Category6Button.Text = 'Category 6';
            app.Category7Button = uitogglebutton(app.CelltypeButtonGroup);
            app.Category7Button.Text = 'Category 7';
            app.Category8Button = uitogglebutton(app.CelltypeButtonGroup);
            app.Category8Button.Text = 'Category 8';
            app.MarksizeLabel = uilabel(app.GridLayout);
            app.MarksizeLabel.Text = 'Mark size';
            app.WidthSpinnerLabel = uilabel(app.GridLayout);
            app.WidthSpinnerLabel.Text = 'Width';
            app.WidthSpinner = uispinner(app.GridLayout);
            app.WidthSpinner.Limits = [0 Inf];
            app.HeightSpinnerLabel = uilabel(app.GridLayout);
            app.HeightSpinnerLabel.Text = 'Height';
            app.HeightSpinner = uispinner(app.GridLayout);
            app.HeightSpinner.Limits = [0 Inf];
            app.xpshiftSwitchLabel = uilabel(app.GridLayout);
            app.xpshiftSwitchLabel.Text = '.5xp shift';
            app.xpshiftSwitch = uiswitch(app.GridLayout, 'slider');
            app.xpshiftSwitch.Items = {'Left', 'Right'};
            app.xpshiftSwitch.Value = 'Left';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Description'; 'Category'};
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = true;
            app.ExistingROIsLabel = uilabel(app.GridLayout);
            app.ExistingROIsLabel.Text = 'Existing ROIs';
            % Show the figure after all components are created
            app.AddcellmarksUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = addRoi(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AddcellmarksUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.AddcellmarksUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddcellmarksUIFigure)
        end
    end
end