classdef blank_template < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        AutocellsdetectCheckBox  matlab.ui.control.CheckBox
        Upload                   matlab.ui.control.Button
        Accept                   matlab.ui.control.Button
        Image                    matlab.ui.control.Image
    end

    
    properties (Access = private)
        mainApp_handle % Description
    end
    
    methods (Access = private)
        
        function acceptBtnDisable(app)
            if ~app.Image.ImageSource
                app.AutocellsdetectCheckBox.Enable="off";
                app.Accept.Enable="off";
                return;
            end
            app.Accept.Enable="on";
            app.AutocellsdetectCheckBox.Enable="on";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Upload
        function UploadButtonPushed(app, event)
            extensions = {'*.tif;*.png;*.jpg;*.jpeg,*.json'};
            [file,path] = uigetfile(extensions);
            if isequal(file,0)
                acceptBtnDisable(app);
                return;
            end
            [~,~,fExt]=fileparts(file);
            if ~any(strcmp({'.jpg','.jpeg','.png','.tif','.json'},fExt))
                errordlg('Not supported file type :(');
                acceptBtnDisable(app);
                return;
            end
            img = imread(strcat(path, file));
            app.Image.ImageSource = img;
            acceptBtnDisable(app);
        end

        % Button pushed function: Accept
        function AcceptButtonPushed(app, event)
            if app.Accept.Enable=="off"
                errordlg('Not supported file type :(');
            end
            app.mainApp_handle = addRoi(app.Image.ImageSource, app.AutocellsdetectCheckBox.Value);
            UIFigureCloseRequest(app, event);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Accept = uibutton(app.GridLayout, 'push');
            app.Accept.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.Accept.Enable = 'off';
            app.Accept.Text = 'Accept =>';
            app.Upload = uibutton(app.GridLayout, 'push');
            app.Upload.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.Upload.Text = 'Upload';
            app.AutocellsdetectCheckBox = uicheckbox(app.GridLayout);
            app.AutocellsdetectCheckBox.Enable = 'off';
            app.AutocellsdetectCheckBox.Text = 'Auto cells detect?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = blank_template

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end