classdef edit_categories < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        EditField_7        matlab.ui.control.EditField
        EditField_8        matlab.ui.control.EditField
        Label_8            matlab.ui.control.Label
        Label_7            matlab.ui.control.Label
        Label_6            matlab.ui.control.Label
        Label_5            matlab.ui.control.Label
        Label_4            matlab.ui.control.Label
        Label_3            matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        Label              matlab.ui.control.Label
        SavechangesButton  matlab.ui.control.Button
        CancelButton       matlab.ui.control.Button
        NewValueLabel      matlab.ui.control.Label
        OldValueLabel      matlab.ui.control.Label
        EditField_6        matlab.ui.control.EditField
        EditField_5        matlab.ui.control.EditField
        EditField_4        matlab.ui.control.EditField
        EditField_3        matlab.ui.control.EditField
        EditField_2        matlab.ui.control.EditField
        EditField          matlab.ui.control.EditField
    end

    
    properties (Access = private)
        img; % Description
        autoDetect;
        filePath;
        prev_rois;
        saved_categories;
        prev_cats;

        parent;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app, prev_cats)
            app.Label.Text = prev_cats(1);
            app.Label_2.Text = prev_cats(2);
            app.Label_3.Text = prev_cats(3);
            app.Label_4.Text = prev_cats(4);
            app.Label_5.Text = prev_cats(5);
            app.Label_6.Text = prev_cats(6);
            app.Label_7.Text = prev_cats(7);
            app.Label_8.Text = prev_cats(8);

            app.parent = parent_app;

            app.prev_cats = prev_cats;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: SavechangesButton
        function SavechangesButtonPushed(app, event)
            if ~strcmp(app.EditField.Value, app.prev_cats(1))
                app.prev_cats(1) = app.EditField.Value;
            end
            if ~strcmp(app.EditField_2.Value, app.prev_cats(2))
                app.prev_cats(2) = app.EditField_2.Value;
            end
            if ~strcmp(app.EditField_3.Value, app.prev_cats(3))
                app.prev_cats(3) = app.EditField_3.Value;
            end
            if ~strcmp(app.EditField_4.Value, app.prev_cats(4))
                app.prev_cats(4) = app.EditField_4.Value;
            end
            if ~strcmp(app.EditField_5.Value, app.prev_cats(5))
                app.prev_cats(5) = app.EditField_5.Value;
            end
            if ~strcmp(app.EditField_6.Value, app.prev_cats(6))
                app.prev_cats(6) = app.EditField_6.Value;
            end
            if ~strcmp(app.EditField_7.Value, app.prev_cats(7))
                app.prev_cats(7) = app.EditField_7.Value;
            end
            if ~strcmp(app.EditField_8.Value, app.prev_cats(8))
                app.prev_cats(8) = app.EditField_8.Value;
            end

            reload_categories(app.parent, app.prev_cats);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField_2 = uieditfield(app.GridLayout, 'text');
            app.EditField_3 = uieditfield(app.GridLayout, 'text');
            app.EditField_4 = uieditfield(app.GridLayout, 'text');
            app.EditField_5 = uieditfield(app.GridLayout, 'text');
            app.EditField_6 = uieditfield(app.GridLayout, 'text');
            app.OldValueLabel = uilabel(app.GridLayout);
            app.OldValueLabel.Text = 'Old Value';
            app.NewValueLabel = uilabel(app.GridLayout);
            app.NewValueLabel.Text = 'New Value';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SavechangesButton = uibutton(app.GridLayout, 'push');
            app.SavechangesButton.ButtonPushedFcn = createCallbackFcn(app, @SavechangesButtonPushed, true);
            app.SavechangesButton.Text = 'Save changes';
            app.Label = uilabel(app.GridLayout);
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_7 = uilabel(app.GridLayout);
            app.Label_8 = uilabel(app.GridLayout);
            app.EditField_8 = uieditfield(app.GridLayout, 'text');
            app.EditField_7 = uieditfield(app.GridLayout, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = edit_categories(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end